classdef appMaquinaSED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                  matlab.ui.Figure
        SobreMenu           matlab.ui.container.Menu
        RelatrioMenu        matlab.ui.container.Menu
        AutoriaMenu         matlab.ui.container.Menu
        MtodoDropDownLabel  matlab.ui.control.Label
        Metodo              matlab.ui.control.DropDown
        Title               matlab.ui.control.Label
        Tabela              matlab.ui.control.Table
        ftuvLabel           matlab.ui.control.Label
        f_field             matlab.ui.control.EditField
        gtuvLabel           matlab.ui.control.Label
        g_field             matlab.ui.control.EditField
        aEditFieldLabel     matlab.ui.control.Label
        a_field             matlab.ui.control.NumericEditField
        bEditFieldLabel     matlab.ui.control.Label
        b_field             matlab.ui.control.NumericEditField
        nEditFieldLabel     matlab.ui.control.Label
        n_field             matlab.ui.control.NumericEditField
        u0EditFieldLabel    matlab.ui.control.Label
        u0_field            matlab.ui.control.NumericEditField
        v0EditFieldLabel    matlab.ui.control.Label
        v0_field            matlab.ui.control.NumericEditField
        CalcularButton      matlab.ui.control.Button
        LimparButton        matlab.ui.control.Button
        SairButton          matlab.ui.control.Button
        Mensagem            matlab.ui.control.Label
        ProblemaLabel       matlab.ui.control.Label
        Metodo_2            matlab.ui.control.DropDown
        Grafico             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CalcularButtonPushed(app);
        end

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
            exitMessage = questdlg("Deseja mesmo sair?", "Sair", "Sim", "Não", "Não");
            
            if strcmp(exitMessage, "Sim")
                close(app.UI);
            end
        end

        % Button pushed function: LimparButton
        function LimparButtonPushed(app, event)
            cla(app.Grafico);
            app.Tabela.Data = {};
            set(app.Tabela, 'columnname', {});
            legend(app.Grafico,'boxoff');
            app.u0_field.Value=0;
            app.v0_field.Value=0;
            app.a_field.Value=0;
            app.b_field.Value=0;
            app.n_field.Value=0;
            app.n_field.Value=0;
            app.f_field.Value='';
            app.g_field.Value='';
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)

            syms u(t) v(t) % Criar duas funções simbólicas
            
            cla(app.Grafico); % Limpar o gráfico
            app.Mensagem.Visible = 'off';
            
            %%%%% Verificação do Input %%%%%
            strF = app.f_field.Value; % Obter o valor no campo da função f
            strG = app.g_field.Value; % Obter o valor no campo da função g
            
            f = @(t,u,v) eval(vectorize(strF)); % Definir a nossa função f
            g = @(t,u,v) eval(vectorize(strG)); % Definir a nossa função g
            try % Testar se a função inserida é válida
                f(t,u,v);
                g(t,u,v);
            catch
                errordlg("Introduza uma função em t,u,v","Erro");
                return
            end
            
            a = app.a_field.Value; % Obter o valor do campo A
            if (not(isscalar(a) && isreal(a))) % O valor A tem de ser real
                errordlg("O número introduzido para a é inválido","Erro");
                return
            end
            
            b = app.b_field.Value; % Obter o valor do campo B
            if (not(isscalar(b) && isreal(b) && b>a)) % O valor B tem de ser real e maior que A
                errordlg("O número introduzido para b é inválido","Erro");
                return
            end
        
            n = app.n_field.Value; % Obter o valor do campo N
            if (not(isscalar(n)))
                errordlg("O número introduzido para n é inválido","Erro");
                return
            end
        
            u0 = app.u0_field.Value; % Obter o valor da condição inicial de u
            if (not(isscalar(u0) && isreal(u0))) % A condição inicial tem de ser real
                errordlg("O número introduzido para u0 é inválido","Erro");
                return
            end
            
            v0 = app.v0_field.Value; % Obter o valor da condição inicial de v
            if (not(isscalar(v0) && isreal(v0))) % A condição inicial tem de ser real
                errordlg("O número introduzido para v0 é inválido","Erro");
                return
            end
            
             %%%%% Adicionar pasta dos métodos ao PATH caso ainda não tenha sido feito %%%%%
            try
                MEuler_SED(f,g,a,b,n,u0,v0);
            catch
                addpath Métodos
            end
            
            %%%% Cálculo da solução exata do problema (se for possível) %%%%%
            try
                S = dsolve(diff(u) == f(t,u,v), diff(v) == g(t,u,v), u(0) == u0, v(0) == v0);
                if(isempty(S))
                    error(S);
                end
                p = @(t) eval(vectorize(char(S.u)));
                k = @(t) eval(vectorize(char(S.v)));
                
                h = (b-a)/n;
                t = a:h:b;
                
                yExataU = zeros(b);
                yExataV = zeros(b);
                yExataU = p(t);
                yExataV = k(t);
                
                plot(app.Grafico,yExataU,'-go','LineWidth',1);
                hold(app.Grafico, "on");
                plot(app.Grafico,yExataV,'-mo','LineWidth',1);
                hold(app.Grafico, "on");
                xlim(app.Grafico, [a b]);
                solExata = 1;
            catch
                app.Mensagem.Visible = 'on';
                solExata = 0;
            end
            
            %%%%% Escolha do método %%%%%
            escolha = app.Metodo.Value;
            
            legend(app.Grafico,'boxon'); %Para prevenir caso limpar
            
            switch escolha
                case "Euler"
                    [t,u,v] = MEuler_SED(f,g,a,b,n,u0,v0);
                case "Euler+"
                    [t,u,v] = MEulerM_SED(f,g,a,b,n,u0,v0);
                case "RK2"
                    [t,u,v] = NRK2_SED(f,g,a,b,n,u0,v0);
                case "RK4"
                    [t,u,v] = NRK4_SED(f,g,a,b,n,u0,v0);
                case "Todos"
                    [~,uEuler,vEuler] = MEuler_SED(f,g,a,b,n,u0,v0);
                    [~,uEulerM,vEulerM] = MEulerM_SED(f,g,a,b,n,u0,v0);
                    [~,uRK2,vRK2] = NRK2_SED(f,g,a,b,n,u0,v0);
                    [t,uRK4,vRK4] = NRK4_SED(f,g,a,b,n,u0,v0);
            end
            
            if(escolha ~= "Todos")
                plot(app.Grafico,t,u,'b--','LineWidth', 2); % Mostrar o gráfico
                hold(app.Grafico, "on"); % Segurar o gráfico
                plot(app.Grafico,t,v,'r-','LineWidth', 2); % Mostrar o gráfico
                hold(app.Grafico, "on"); % Segurar o gráfico
                xlim(app.Grafico, [a b]);
                
                st = 0:1:n;
                if(solExata == 1)
                    legend(app.Grafico,'uExata','vExata',strcat('u', escolha),strcat('v', escolha),'Location','northwest'); % Legenda do gráfico
                    uErro = yExataU - u;
                    vErro = yExataV - v;
                    uErro = abs(uErro);
                    vErro = abs(vErro);
                    tabela = [st.',t.',yExataU.',yExataV.',u.',uErro.',v.',vErro.']; % Criação da tabela
                    set(app.Tabela, 'columnname', {'Iteração', 't','uExata','vExata',strcat('u', escolha),strcat('uErro', escolha),strcat('v', escolha),strcat('vErro', escolha)}); % Nomes das colunas da tabela
                else
                    legend(app.Grafico,strcat('u', escolha),strcat('v', escolha),'Location','northwest'); % Legenda do gráfico
                    tabela = [st.',t.',u.',v.']; % Criação da tabela
                    set(app.Tabela, 'columnname', {'Iteração', 't', strcat('u', escolha), strcat('v', escolha)}); % Nomes das colunas da tabela
                end
                
                app.Tabela.Data = tabela; % Preencher a tabela com os valores obtidos
            else
                plot(app.Grafico,t,uEuler,'b--','LineWidth', 2); % Mostrar o gráfico
                hold(app.Grafico, "on"); % Segurar o gráfico
                plot(app.Grafico,t,vEuler,'r-','LineWidth', 2); % Mostrar o gráfico
                hold(app.Grafico, "on"); % Segurar o gráfico
                
                plot(app.Grafico,t,uEulerM,'k--','LineWidth', 2); % Mostrar o gráfico
                hold(app.Grafico, "on"); % Segurar o gráfico
                plot(app.Grafico,t,vEulerM,'y-','LineWidth', 2); % Mostrar o gráfico
                hold(app.Grafico, "on"); % Segurar o gráfico
                
                plot(app.Grafico,t,uRK2,'c--','LineWidth', 2); % Mostrar o gráfico
                hold(app.Grafico, "on"); % Segurar o gráfico
                plot(app.Grafico,t,vRK2,'m-','LineWidth', 2); % Mostrar o gráfico
                hold(app.Grafico, "on"); % Segurar o gráfico
                
                plot(app.Grafico,t,uRK4,'g--','LineWidth', 2); % Mostrar o gráfico
                hold(app.Grafico, "on"); % Segurar o gráfico
                plot(app.Grafico,t,vRK4,'Color',[1 0.5 0],'LineWidth', 2); % Mostrar o gráfico
                hold(app.Grafico, "on"); % Segurar o gráfico
                
                st = 0:1:n;
                
                if(solExata == 1)
                    uErroEuler = yExataU - uEuler;
                    vErroEuler = yExataV - vEuler;
                    uErroEulerM = yExataU - uEulerM;
                    vErroEulerM = yExataV - vEulerM;
                    uErroRK2 = yExataU - uRK2;
                    vErroRK2 = yExataV - vRK2;
                    uErroRK4 = yExataU - uRK4;
                    vErroRK4 = yExataV - vRK4;
                    
                    uErroEuler = abs(uErroEuler);
                    vErroEuler = abs(vErroEuler);
                    uErroEulerM = abs(uErroEulerM);
                    vErroEulerM = abs(vErroEulerM);
                    uErroRK2 = abs(uErroRK2);
                    vErroRK2 = abs(vErroRK2);
                    uErroRK4 = abs(uErroRK4);
                    vErroRK4 = abs(vErroRK4);
                    
                    legend(app.Grafico,'uExata','vExata','uEuler','vEuler', 'uEuler+', 'vEuler+', 'uRK2', 'vRK2', 'uRK4', 'vRK4','Location','northwest'); % Legenda do gráfico
                    tabela = [st.',t.',yExataU.',yExataV.',uEuler.',uErroEuler.',vEuler.',vErroEuler.',uEulerM.',uErroEulerM.',vEulerM.',vErroEulerM.',uRK2.',uErroRK2.',vRK2.',vErroRK2.',uRK4.',uErroRK4.',vRK4.',vErroRK4.']; % Criação da tabela
                    set(app.Tabela, 'columnname', {'Iteração', 't','uExata','vExata','uEuler','uErroEuler','vEuler','vErroEuler','uEuler+','uErroEuler+','vEuler+','vErroEuler+','uRK2','uErroRK2','vRK2','vErroRK2','uRK4','uErroRK4','vRK4','vErroRK4'}); % Nomes das colunas da tabela
                else
                    legend(app.Grafico,'uEuler','vEuler', 'uEuler+', 'vEuler+', 'uRK2', 'vRK2', 'uRK4', 'vRK4','Location','northwest'); % Legenda do gráfico
                    tabela = [st.',t.',uEuler.',vEuler.',uEulerM.',vEulerM.',uRK2.',vRK2.',uRK4.',vRK4.']; % Criação da tabela
                    set(app.Tabela, 'columnname', {'Iteração', 't', 'uEuler', 'vEuler', 'uEuler+', 'vEuler+', 'uRK2', 'vRK2', 'uRK4', 'vRK4'}); % Nomes das colunas da tabela
                end
               
                app.Tabela.Data = tabela; % Preencher a tabela com os valores obtidos
            end
        end

        % Menu selected function: AutoriaMenu
        function AutoriaMenuSelected(app, event)
                        
            %Mensagem pop up de text com informacao a respeito dos autores
            %da app
            mh = msgbox(["Tomás Silva  a2020143845@isec.pt"; "Tomás Pinto  a2020144067@isec.pt"; "Francisco Mendes  a2020143982@isec.pt"], "Autores");
            
            %Restruturacao da box pop up para aumentar o tamanho da fonte e
            %da janela
            th = findall(mh, 'Type', 'Text');  
            th.FontSize = 10; 
            
            deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
            deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
            mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
          
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open('Trabalho 2 - AM2.pdf');
        end

        % Value changed function: Metodo_2
        function Metodo_2ValueChanged(app, event)
            value = app.Metodo_2.Value;
            switch value
                case "Personalizado"
                    LimparButtonPushed(app);
                    app.f_field.Editable = 'on';
                    app.g_field.Editable = 'on';
                    app.a_field.Editable = 'on';
                    app.b_field.Editable = 'on';
                    app.n_field.Editable = 'on';
                    app.u0_field.Editable = 'on';
                    app.v0_field.Editable = 'on';
                case "Problema do Pêndulo"
                    app.f_field.Editable = 'off';
                    app.g_field.Editable = 'off';
                    app.a_field.Editable = 'off';
                    app.b_field.Editable = 'off';
                    app.n_field.Editable = 'off';
                    app.u0_field.Editable = 'off';
                    app.v0_field.Editable = 'off';
                    
                    app.f_field.Value = "v";
                    app.g_field.Value = "-sin(u)-0.3*v";
                    app.a_field.Value = 0;
                    app.b_field.Value = 15;
                    app.n_field.Value = 100;
                    app.u0_field.Value = 1.571;
                    app.v0_field.Value = 0;
                    CalcularButtonPushed(app);
                case "Mola-Massa com Amortecimento"
                    app.f_field.Editable = 'off';
                    app.g_field.Editable = 'off';
                    app.a_field.Editable = 'off';
                    app.b_field.Editable = 'off';
                    app.n_field.Editable = 'off';
                    app.u0_field.Editable = 'off';
                    app.v0_field.Editable = 'off';
                    
                    app.f_field.Value = "v";
                    app.g_field.Value = "-25*u-10*v";
                    app.a_field.Value = 0;
                    app.b_field.Value = 2;
                    app.n_field.Value = 100;
                    app.u0_field.Value = 0;
                    app.v0_field.Value = -4;
                    CalcularButtonPushed(app);
                case "Movimento Harmónico Simples"
                    app.f_field.Editable = 'off';
                    app.g_field.Editable = 'off';
                    app.a_field.Editable = 'off';
                    app.b_field.Editable = 'off';
                    app.n_field.Editable = 'off';
                    app.u0_field.Editable = 'off';
                    app.v0_field.Editable = 'off';
                    
                    app.f_field.Value = "v";
                    app.g_field.Value = "-16*u";
                    app.a_field.Value = 0;
                    app.b_field.Value = 10;
                    app.n_field.Value = 100;
                    app.u0_field.Value = 9;
                    app.v0_field.Value = 0;
                    CalcularButtonPushed(app);
                case "Modelos Vibratórios Mecânicos"
                    app.f_field.Editable = 'off';
                    app.g_field.Editable = 'off';
                    app.a_field.Editable = 'off';
                    app.b_field.Editable = 'off';
                    app.n_field.Editable = 'off';
                    app.u0_field.Editable = 'off';
                    app.v0_field.Editable = 'off';
                    
                    app.f_field.Value = "v";
                    app.g_field.Value = "-2*sin(t)+4*cos(t)-2*u-2*v";
                    app.a_field.Value = 0;
                    app.b_field.Value = 15;
                    app.n_field.Value = 100;
                    app.u0_field.Value = 0;
                    app.v0_field.Value = 3;
                    CalcularButtonPushed(app);
                case "Circuito Elétrico"
                    app.f_field.Editable = 'off';
                    app.g_field.Editable = 'off';
                    app.a_field.Editable = 'off';
                    app.b_field.Editable = 'off';
                    app.n_field.Editable = 'off';
                    app.u0_field.Editable = 'off';
                    app.v0_field.Editable = 'off';
                    
                    app.f_field.Value = "v";
                    app.g_field.Value = "-6*v-25*u";
                    app.a_field.Value = 0;
                    app.b_field.Value = 20;
                    app.n_field.Value = 100;
                    app.u0_field.Value = 4;
                    app.v0_field.Value = -4;
                    CalcularButtonPushed(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure('Visible', 'off');
            app.UI.Name = 'Máquina SED';
            app.UI.Icon = 'logo-isec-transparente.png';
            app.SobreMenu = uimenu(app.UI);
            app.SobreMenu.Text = 'Sobre';
            app.RelatrioMenu = uimenu(app.SobreMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.AutoriaMenu = uimenu(app.SobreMenu);
            app.AutoriaMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoriaMenuSelected, true);
            app.AutoriaMenu.Text = 'Autoria';
            app.MtodoDropDownLabel = uilabel(app.UI);
            app.MtodoDropDownLabel.Text = 'Método';
            app.Metodo = uidropdown(app.UI);
            app.Metodo.Items = {'Euler', 'Euler+', 'RK2', 'RK4', 'Todos'};
            app.Metodo.Value = 'Euler';
            app.Title = uilabel(app.UI);
            app.Title.Text = 'Resolução de Sistemas de Equações Diferenciais (SED)';
            app.Tabela = uitable(app.UI);
            app.Tabela.ColumnName = {'Iterações'; 't'; 'u'; 'v'};
            app.Tabela.RowName = {};
            app.ftuvLabel = uilabel(app.UI);
            app.ftuvLabel.Text = 'f (t, u, v)';
            app.f_field = uieditfield(app.UI, 'text');
            app.f_field.Editable = 'off';
            app.f_field.Tooltip = {'A caixa estará desativada se um problema tiver sido selecionado.'};
            app.f_field.Value = 'v';
            app.gtuvLabel = uilabel(app.UI);
            app.gtuvLabel.Text = 'g (t, u, v)';
            app.g_field = uieditfield(app.UI, 'text');
            app.g_field.Editable = 'off';
            app.g_field.Tooltip = {'A caixa estará desativada se um problema tiver sido selecionado.'};
            app.g_field.Value = '-sin(u)-0.3*v';
            app.aEditFieldLabel = uilabel(app.UI);
            app.aEditFieldLabel.Text = 'a';
            app.a_field = uieditfield(app.UI, 'numeric');
            app.a_field.ValueDisplayFormat = '%.0f';
            app.a_field.Editable = 'off';
            app.a_field.Tooltip = {'A caixa estará desativada se um problema tiver sido selecionado.'};
            app.bEditFieldLabel = uilabel(app.UI);
            app.bEditFieldLabel.Text = 'b';
            app.b_field = uieditfield(app.UI, 'numeric');
            app.b_field.Editable = 'off';
            app.b_field.Tooltip = {'A caixa estará desativada se um problema tiver sido selecionado.'};
            app.b_field.Value = 15;
            app.nEditFieldLabel = uilabel(app.UI);
            app.nEditFieldLabel.Text = 'n';
            app.n_field = uieditfield(app.UI, 'numeric');
            app.n_field.Editable = 'off';
            app.n_field.Tooltip = {'A caixa estará desativada se um problema tiver sido selecionado.'};
            app.n_field.Value = 100;
            app.u0EditFieldLabel = uilabel(app.UI);
            app.u0EditFieldLabel.Text = 'u0';
            app.u0_field = uieditfield(app.UI, 'numeric');
            app.u0_field.Editable = 'off';
            app.u0_field.Tooltip = {'A caixa estará desativada se um problema tiver sido selecionado.'};
            app.u0_field.Value = 1.571;
            app.v0EditFieldLabel = uilabel(app.UI);
            app.v0EditFieldLabel.Text = 'v0';
            app.v0_field = uieditfield(app.UI, 'numeric');
            app.v0_field.Editable = 'off';
            app.v0_field.Tooltip = {'A caixa estará desativada se um problema tiver sido selecionado.'};
            app.CalcularButton = uibutton(app.UI, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimparButton = uibutton(app.UI, 'push');
            app.LimparButton.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.LimparButton.Text = 'Limpar';
            app.SairButton = uibutton(app.UI, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            app.Mensagem = uilabel(app.UI);
            app.Mensagem.Text = 'Não foi possível calcular a solução exata';
            app.ProblemaLabel = uilabel(app.UI);
            app.ProblemaLabel.Text = 'Problema';
            app.Metodo_2 = uidropdown(app.UI);
            app.Metodo_2.Items = {'Personalizado', 'Problema do Pêndulo', 'Mola-Massa com Amortecimento', 'Movimento Harmónico Simples', 'Modelos Vibratórios Mecânicos', 'Circuito Elétrico'};
            app.Metodo_2.ValueChangedFcn = createCallbackFcn(app, @Metodo_2ValueChanged, true);
            app.Metodo_2.Tooltip = {'Para alterar os valores por favor escolha a opção "Personalizado".'};
            app.Metodo_2.Value = 'Problema do Pêndulo';
            app.Grafico = uiaxes(app.UI);
            zlabel(app.Grafico, 'Z')
            % Show the figure after all components are created
            app.UI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaSED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end